classdef number_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        PushTool3                   matlab.ui.container.toolbar.PushTool
        PushTool2                   matlab.ui.container.toolbar.PushTool
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout7_4               matlab.ui.container.GridLayout
        EccentricitySpinner_4       matlab.ui.control.Spinner
        TagDropDownLabel_4          matlab.ui.control.Label
        EccentricitySpinner_3       matlab.ui.control.Spinner
        TagDropDownLabel_3          matlab.ui.control.Label
        TagDropDownLabel_2          matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        GridLayout8                 matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        OutputDropDown              matlab.ui.control.DropDown
        OutputDropDownLabel         matlab.ui.control.Label
        ColorButtonGroup            matlab.ui.container.ButtonGroup
        TagDropDown                 matlab.ui.control.DropDown
        TagDropDownLabel            matlab.ui.control.Label
        Copyright2023cbzLabel       matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        FontButtonGroup             matlab.ui.container.ButtonGroup
        DubaiCheckBox               matlab.ui.control.CheckBox
        CheckBox                    matlab.ui.control.CheckBox
        YuGothicCheckBox            matlab.ui.control.CheckBox
        CalibriCheckBox             matlab.ui.control.CheckBox
        GridLayout7_3               matlab.ui.container.GridLayout
        ONButton                    matlab.ui.control.StateButton
        OverlayerLabel              matlab.ui.control.Label
        EulerNumberSpinner_2        matlab.ui.control.Spinner
        EulerNumberSpinner_2Label   matlab.ui.control.Label
        Tagconditon2ButtonGroup     matlab.ui.container.ButtonGroup
        H_ExtentSpinner             matlab.ui.control.Spinner
        H_ExtentSpinnerLabel        matlab.ui.control.Label
        L_ExtentSpinner             matlab.ui.control.Spinner
        L_ExtentSpinnerLabel        matlab.ui.control.Label
        EccentricitySpinner_2       matlab.ui.control.Spinner
        EccentricitySpinner_2Label  matlab.ui.control.Label
        WHSpinner                   matlab.ui.control.Spinner
        whLabel                     matlab.ui.control.Label
        GridLayout7_2               matlab.ui.container.GridLayout
        MergenceLabel               matlab.ui.control.Label
        OFFButton                   matlab.ui.control.StateButton
        EulerNumberSpinner          matlab.ui.control.Spinner
        EulerNumberSpinnerLabel     matlab.ui.control.Label
        Tagconditon1ButtonGroup     matlab.ui.container.ButtonGroup
        H_ExtentSpinner_2           matlab.ui.control.Spinner
        H_ExtentSpinner_2Label      matlab.ui.control.Label
        L_ExtentSpinner_2           matlab.ui.control.Spinner
        L_ExtentSpinner_2Label      matlab.ui.control.Label
        EccentricitySpinner         matlab.ui.control.Spinner
        EccentricitySpinnerLabel    matlab.ui.control.Label
        WHSpinner_2                 matlab.ui.control.Spinner
        WHSpinner_2Label            matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        OriginTab                   matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        Label                       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        RotationTab                 matlab.ui.container.Tab
        GridLayout2_2               matlab.ui.container.GridLayout
        Label_5                     matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
        Tag1Tab                     matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        Label_2                     matlab.ui.control.Label
        UIAxes_1                    matlab.ui.control.UIAxes
        Tag2Tab                     matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        Label_3                     matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        FinalTab                    matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        Label_4                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        global_img = [];
        celltexts = {};
        celltexts_2 = {};
        DialogApp;
        isopen = 0;
        test = 1;
        p = 'on';
    end
    
    methods (Access = private)
        
        %载入图像
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                img = imread('corn.tif');
            else
                try
                    img = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 

            
            imshow(img,'Parent',app.UIAxes);
            app.PushTool2.Enable = 'on';
            app.PushTool3.Enable = 'on';


            app.global_img = img;

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.celltexts{1} = ''; % 赋初值
        end

        % Clicked callback: PushTool
        function ButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg;*.bmp','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];

               
               updateimage(app, fname);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp);
            delete(app);
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            processing_img(app, app.global_img);
        end

        % Value changed function: OFFButton
        function OFFButtonValueChanged(app, event)
            value = app.OFFButton.Value;
            if value
                app.OFFButton.Text = 'ON';
            else
                app.OFFButton.Text = 'OFF';
            end
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            confirm_state = uiconfirm(app.UIFigure,'When interaction is enabled, you can zoom in and move the image, but this may result in abnormal lagging of the application.','Interaction control','Icon','warning','Options',{'Enable','Disable','Cancel'});
            switch confirm_state
                case 'Enable'
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.Label_4.Visible = 'off';
                    app.p = 'off';
                case 'Disable'
                    app.Label.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_4.Visible = 'on';
                    app.p = 'on';
            end
        end

        % Value changed function: ONButton
        function ONButtonValueChanged(app, event)
            value = app.ONButton.Value;
            if value
                app.ONButton.Text = 'ON';
            else
                app.ONButton.Text = 'OFF';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {''};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @ButtonPushed, true);
            app.PushTool.Icon = 'img.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Enable = 'off';
            app.PushTool3.Icon = 'open.png';
            app.PushTool3.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Enable = 'off';
            app.PushTool2.Icon = 'start.png';
            app.PushTool2.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.OriginTab = uitab(app.TabGroup);
            app.OriginTab.Title = 'Origin';
            app.GridLayout2 = uigridlayout(app.OriginTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.GridLayout2_2 = uigridlayout(app.RotationTab);
            app.UIAxes_3 = uiaxes(app.GridLayout2_2);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Label_5 = uilabel(app.GridLayout2_2);
            app.Label_5.Text = '';
            app.Tag1Tab = uitab(app.TabGroup);
            app.Tag1Tab.Title = 'Tag 1';
            app.GridLayout3 = uigridlayout(app.Tag1Tab);
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.Tag2Tab = uitab(app.TabGroup);
            app.Tag2Tab.Title = 'Tag 2';
            app.GridLayout4 = uigridlayout(app.Tag2Tab);
            app.UIAxes_2 = uiaxes(app.GridLayout4);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '';
            app.FinalTab = uitab(app.TabGroup);
            app.FinalTab.Title = 'Final';
            app.GridLayout5 = uigridlayout(app.FinalTab);
            app.Panel = uipanel(app.GridLayout5);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.WHSpinner_2Label = uilabel(app.GridLayout7_2);
            app.WHSpinner_2Label.Text = 'W / H';
            app.WHSpinner_2 = uispinner(app.GridLayout7_2);
            app.WHSpinner_2.Step = 0.5;
            app.WHSpinner_2.Limits = [0 Inf];
            app.WHSpinner_2.Value = 3;
            app.EccentricitySpinnerLabel = uilabel(app.GridLayout7_2);
            app.EccentricitySpinnerLabel.Text = 'Eccentricity';
            app.EccentricitySpinner = uispinner(app.GridLayout7_2);
            app.EccentricitySpinner.Step = 0.05;
            app.EccentricitySpinner.Limits = [0 1];
            app.EccentricitySpinner.Value = 0.995;
            app.L_ExtentSpinner_2Label = uilabel(app.GridLayout7_2);
            app.L_ExtentSpinner_2Label.Text = 'L_Extent';
            app.L_ExtentSpinner_2 = uispinner(app.GridLayout7_2);
            app.L_ExtentSpinner_2.Step = 0.05;
            app.L_ExtentSpinner_2.Value = 0.2;
            app.H_ExtentSpinner_2Label = uilabel(app.GridLayout7_2);
            app.H_ExtentSpinner_2Label.Text = 'H_Extent';
            app.H_ExtentSpinner_2 = uispinner(app.GridLayout7_2);
            app.H_ExtentSpinner_2.Step = 0.05;
            app.H_ExtentSpinner_2.Value = 0.87;
            app.Tagconditon1ButtonGroup = uibuttongroup(app.GridLayout7_2);
            app.Tagconditon1ButtonGroup.Title = 'Tag conditon 1';
            app.EulerNumberSpinnerLabel = uilabel(app.GridLayout7_2);
            app.EulerNumberSpinnerLabel.Text = 'EulerNumber';
            app.EulerNumberSpinner = uispinner(app.GridLayout7_2);
            app.EulerNumberSpinner.Value = -6;
            app.OFFButton = uibutton(app.GridLayout7_2, 'state');
            app.OFFButton.ValueChangedFcn = createCallbackFcn(app, @OFFButtonValueChanged, true);
            app.OFFButton.Text = 'OFF';
            app.MergenceLabel = uilabel(app.GridLayout7_2);
            app.MergenceLabel.Text = 'Mergence';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.whLabel = uilabel(app.GridLayout7_3);
            app.whLabel.Text = 'W / H';
            app.WHSpinner = uispinner(app.GridLayout7_3);
            app.WHSpinner.Step = 0.5;
            app.WHSpinner.Limits = [0 Inf];
            app.WHSpinner.Value = 1;
            app.EccentricitySpinner_2Label = uilabel(app.GridLayout7_3);
            app.EccentricitySpinner_2Label.Text = 'Eccentricity';
            app.EccentricitySpinner_2 = uispinner(app.GridLayout7_3);
            app.EccentricitySpinner_2.Step = 0.05;
            app.EccentricitySpinner_2.Limits = [0 1];
            app.EccentricitySpinner_2.Value = 0.995;
            app.L_ExtentSpinnerLabel = uilabel(app.GridLayout7_3);
            app.L_ExtentSpinnerLabel.Text = 'L_Extent';
            app.L_ExtentSpinner = uispinner(app.GridLayout7_3);
            app.L_ExtentSpinner.Step = 0.05;
            app.L_ExtentSpinner.Value = 0.2;
            app.H_ExtentSpinnerLabel = uilabel(app.GridLayout7_3);
            app.H_ExtentSpinnerLabel.Text = 'H_Extent';
            app.H_ExtentSpinner = uispinner(app.GridLayout7_3);
            app.H_ExtentSpinner.Step = 0.05;
            app.H_ExtentSpinner.Value = 0.9;
            app.Tagconditon2ButtonGroup = uibuttongroup(app.GridLayout7_3);
            app.Tagconditon2ButtonGroup.Title = 'Tag conditon 2';
            app.EulerNumberSpinner_2Label = uilabel(app.GridLayout7_3);
            app.EulerNumberSpinner_2Label.Text = 'EulerNumber';
            app.EulerNumberSpinner_2 = uispinner(app.GridLayout7_3);
            app.EulerNumberSpinner_2.Value = -6;
            app.OverlayerLabel = uilabel(app.GridLayout7_3);
            app.OverlayerLabel.Text = 'Overlayer';
            app.ONButton = uibutton(app.GridLayout7_3, 'state');
            app.ONButton.ValueChangedFcn = createCallbackFcn(app, @ONButtonValueChanged, true);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.CalibriCheckBox = uicheckbox(app.GridLayout7);
            app.CalibriCheckBox.Text = 'Calibri';
            app.CalibriCheckBox.Value = true;
            app.YuGothicCheckBox = uicheckbox(app.GridLayout7);
            app.YuGothicCheckBox.Text = 'Yu Gothic';
            app.YuGothicCheckBox.Value = true;
            app.CheckBox = uicheckbox(app.GridLayout7);
            app.CheckBox.Text = '黑体';
            app.CheckBox.Value = true;
            app.DubaiCheckBox = uicheckbox(app.GridLayout7);
            app.DubaiCheckBox.Text = 'Dubai';
            app.DubaiCheckBox.Value = true;
            app.FontButtonGroup = uibuttongroup(app.GridLayout7);
            app.FontButtonGroup.Title = 'Font';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.Copyright2023cbzLabel = uilabel(app.GridLayout8);
            app.Copyright2023cbzLabel.Text = 'Copyright ©2023 cbz';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.TagDropDownLabel = uilabel(app.GridLayout9);
            app.TagDropDownLabel.Text = 'Tag';
            app.TagDropDown = uidropdown(app.GridLayout9);
            app.TagDropDown.Items = {'Red', 'Yellow', 'Blue', 'Green'};
            app.TagDropDown.Value = 'Red';
            app.ColorButtonGroup = uibuttongroup(app.GridLayout9);
            app.ColorButtonGroup.Title = 'Color';
            app.OutputDropDownLabel = uilabel(app.GridLayout9);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.GridLayout9);
            app.OutputDropDown.Items = {'Gray', 'B/W', 'RGB'};
            app.OutputDropDown.Value = 'Gray';
            app.GridLayout7_4 = uigridlayout(app.GridLayout6);
            app.DropDown = uidropdown(app.GridLayout7_4);
            app.DropDown.Items = {'Number', 'Letter', 'Both'};
            app.DropDown.Value = 'Both';
            app.TagDropDownLabel_2 = uilabel(app.GridLayout7_4);
            app.TagDropDownLabel_2.Text = 'Type';
            app.TagDropDownLabel_3 = uilabel(app.GridLayout7_4);
            app.TagDropDownLabel_3.Text = 'Imbinarize';
            app.EccentricitySpinner_3 = uispinner(app.GridLayout7_4);
            app.EccentricitySpinner_3.Step = 0.05;
            app.EccentricitySpinner_3.Limits = [0 1];
            app.EccentricitySpinner_3.Value = 0.5;
            app.TagDropDownLabel_4 = uilabel(app.GridLayout7_4);
            app.TagDropDownLabel_4.Text = 'Rotation';
            app.EccentricitySpinner_4 = uispinner(app.GridLayout7_4);
            app.EccentricitySpinner_4.Step = 0.05;
            app.EccentricitySpinner_4.Limits = [0 1];
            app.EccentricitySpinner_4.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = number_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end