classdef sub_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2_3     matlab.ui.container.GridLayout
        TextArea_2        matlab.ui.control.TextArea
        OCRoutputLabel    matlab.ui.control.Label
        GridLayout2_2     matlab.ui.container.GridLayout
        Label             matlab.ui.control.Label
        ImageoutputLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
        GridLayout2       matlab.ui.container.GridLayout
        TextArea          matlab.ui.control.TextArea
        TRoutputLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, texts, texts_2, img)
            app.CallingApp = mainapp;
            app.TextArea.Value = texts;
            app.TextArea_2.Value = texts_2;
            app.Label.Visible = app.CallingApp.p;
            imshow(img,'Parent',app.UIAxes);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.isopen = 0;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TRoutputLabel = uilabel(app.GridLayout2);
            app.TRoutputLabel.Text = 'TR output';
            app.TextArea = uitextarea(app.GridLayout2);
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageoutputLabel = uilabel(app.GridLayout2_2);
            app.ImageoutputLabel.Text = 'Image output';
            app.Label = uilabel(app.GridLayout2_2);
            app.Label.Text = '';
            app.GridLayout2_3 = uigridlayout(app.GridLayout);
            app.OCRoutputLabel = uilabel(app.GridLayout2_3);
            app.OCRoutputLabel.Text = 'OCR output';
            app.TextArea_2 = uitextarea(app.GridLayout2_3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sub_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end